classdef lab02_app_solved < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Label     matlab.ui.control.Label
        Y0        matlab.ui.control.Slider
        H         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function calc(app, y0)
            t = 0:0.01:5; % вектор времени
            y = y0 - 9.8 * t.^2 / 2; % вектор координат
            plot(app.H, t, y) % построение графика
            ylim(app.H, [0 100]) % пределы осей по Y
            xlim(app.H, [0 5]) % пределы осей по X
        end
    
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.calc(app.Y0.Value);
            app.UIFigure.Name = app.UIFigure.Name + " (ЦИТМ Экспонента)";
        end

        % Value changing function: Y0
        function Y0ValueChanging(app, event)
            changingValue = event.Value;
            app.calc(changingValue);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Симулятор свободного падения';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Начальная высота, м';
            app.Y0 = uislider(app.UIFigure);
            app.Y0.ValueChangingFcn = createCallbackFcn(app, @Y0ValueChanging, true);
            app.Y0.Value = 100;
            app.H = uiaxes(app.UIFigure);
            title(app.H, 'Высота')
            xlabel(app.H, 't, с')
            ylabel(app.H, 'м')
        end
    end

    methods (Access = public)

        % Construct app
        function app = lab02_app_solved

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end