classdef lab01_explosion_example < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        Label              matlab.ui.control.Label
        Edit_H             matlab.ui.control.NumericEditField
        YEditField_2Label  matlab.ui.control.Label
        Edit_Y             matlab.ui.control.NumericEditField
        ZEditField_2Label  matlab.ui.control.Label
        Edit_Z             matlab.ui.control.NumericEditField
        Label_2            matlab.ui.control.Label
        Button             matlab.ui.control.Button
        YEditField_3Label  matlab.ui.control.Label
        Edit_N             matlab.ui.control.NumericEditField
        ZEditField_3Label  matlab.ui.control.Label
        Edit_M             matlab.ui.control.NumericEditField
        XEditFieldLabel    matlab.ui.control.Label
        Edit_X             matlab.ui.control.NumericEditField
        Label_3            matlab.ui.control.Label
        Slider_az          matlab.ui.control.Slider
        Label_4            matlab.ui.control.Label
        Slider_el          matlab.ui.control.Slider
        UIAxes_pict        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function dXdt = steklo_dyn(~,t,X, beta)   
            x = X(1);
            y = X(2);
            z = X(3);
            vx = X(4);
            vy = X(5);
            vz = X(6);            
            V = (vx^2 + vy^2 + vz^2)^(1/2);        
            dXdt = [vx; vy; vz; -beta*V*vx; -beta*V*vy; -beta*V*vz - 9.8];
        end        
        
        function [position,isterminal,direction] = event_zero(~, t,X, H)
            position = X(3)+H; % The value that we want to be zero
            isterminal = 1;  % Halt integration 
            direction = 0;   % The zero can be approached from either direction
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            imshow('explosion.png','Parent',app.UIAxes_pict);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Здесь пишется скрипт, срабатывающий при нажатии на кнопку
            
            cx = 0.2;       % коэффициент вязкого трения для плохообтекаемого тела
            ro_v = 1.225;   % плотность воздуха
            ro_st = 2500;   % плотность стекла
            kapa = 0;       % энергия разрушения стекла на единицу прощади
            d = 0.002;      % толщина стекла
            eta = 0.5;      % доля энергии УВ, передаваемой стеклу
            g = 9.8;        % ускорение свободного падения
            E_uv = 100000;  % энергия взрыва
            L_st = 1;       % длинна стекла
            H_st = 1.5;     % высота стекла
            H = app.Edit_H.Value;         % высота окна над землей
            N = app.Edit_N.Value;         % разбиение по горизонтали
            M = app.Edit_M.Value;         % разбиение по вертикали
            
            % положение заряда
            x_z = app.Edit_X.Value; 
            y_z = app.Edit_Y.Value;
            z_z = app.Edit_Z.Value;
            
            % Параметры элемента стекла
            dy = L_st/N;
            dz = H_st/M;
            
            floor = [0;0;0;0;0;0]; % сбор упавших осколков
            
            K = 0;          % счетчик отрисовки траекторий осколков
            
            % общие настройки стиля графика
            %figure('Color','white');
                 hold(app.UIAxes, 'on')
                 set(app.UIAxes, 'Box','on','TickLabelInterpreter', 'latex', ...
                'FontName','Times New Roman', 'FontSize', 14, ...
                'XGrid', 'on', 'YGrid', 'on', 'ZGrid', 'on', ...
                'GridColor', '[0,0,0]', 'GridLineStyle', '--', 'GridAlpha', 0.3);     
                
                % настройки абсцисс
                set(app.UIAxes, 'XLim',[-5,35], ...
                    'XTick', [-5, 0, 5, 10, 15, 20, 25, 30, 35], ...
                    'XTickLabel', {-5 0 5 10 15 20 25 30 '$x$,\textit{m}'} )
            
                % настройки ординат
                set(app.UIAxes, 'Ylim',[-4,12], ...
                    'YTick', [-4, -2, 0, 2, 4, 6, 8, 10, 12],...
                    'YTickLabel', {-4 -2 0 2 4 6 8 10 '$y$,\textit{m}'} )
            
                % настройки вертикальной оси
                set(app.UIAxes, 'Zlim',[-10.01,5], ...
                    'ZTick', [-10, -9 , -6, -3, 0, 3, 5],...
                    'ZTickLabel', {-10 ''  -6 -3 0 3 '$z$,\textit{m}'} )
                
                %hold on
                
                % Отрисовка краёв оконного проёма
                plot(app.UIAxes,[0,0], [0,L_st],       'ZData', [0,0],         'Color', 'Black','LineWidth', 3)
                plot(app.UIAxes,[0,0], [0,L_st],       'ZData', [H_st,H_st],   'Color', 'Black','LineWidth', 3)
                plot(app.UIAxes,[0,0], [0,0],          'ZData', [0,H_st],      'Color', 'Black','LineWidth', 3)
                plot(app.UIAxes,[0,0], [L_st,L_st],    'ZData', [0,H_st],      'Color', 'Black','LineWidth', 3)
            
            % Просчет каждого осколка стекла    
            for i= 1:1:N
                
                for j = 1:1:M    
                    
                    % Координаты элемента стекла
                    y = dy/2 + (i-1)*dy;
                    z = dz/2 + (j-1)*dz;        
                    
                    S_sr = (dy*dz + dy*d + dz*d)/3; % средняя площадь осколка
                    m = dy*dz*d*ro_st;              % масса осколка
                    
                    r = (x_z^2 + (y-y_z)^2 + (z-z_z)^2)^(1/2); % Расстояние от бомбы до осколка
                    v_0 = ((1/(ro_st*d))*(((eta*E_uv * (-x_z))/(2*pi*r^3)) - kapa ))^(1/2); % начальная скорость осколка
            
                    % Вектор начальных условий
                    X_0 = [0; y; z];
                    V_0 = v_0 * [(-x_z/r) ; ((y-y_z)/r) ; ((z-z_z)/r)];
                    Start_cond = [X_0; V_0];
            
                    t = [0 10];     % Максимально просчитываемый временной промежуток 
                    
                    opts = odeset('Events',@(t,X) event_zero(app,t,X,H), 'MaxStep', 1e-02); % Остановка рассчета при достижении земли
                    beta = (S_sr*cx*ro_v)/(2*m);                                        % Коэффициент в системе дифф. уравнений
                    B = ode45(@(t,X) steklo_dyn(app,t,X,beta), t, Start_cond, opts );       % Решение системы ОДУ
                    
                    % Отрисовка линии от ВУ до элемента окна
                    line(app.UIAxes,'XData',[x_z 0],'YData',[y_z y],'ZData', [z_z, z], ...
                        'Color', 'Red', 'LineStyle', '--' ) 
                    
                    % Отрисовка некоторых траекторий полета осколков
                    if mod(K,13)==0
                        plot(app.UIAxes,B.y(1,:),B.y(2,:), 'ZData', B.y(3,:) , 'Color', 'Black', 'LineStyle', ':')            
                    end
                    
                    K = K+1;
                    floor = [floor, B.ye];  % Пополнение статистики
                    
                end
            end
            
            % Отрисовка мест попадания осколков на землю
            plot(app.UIAxes,floor(1,2:end),floor(2,2:end), 'ZData', floor(3,2:end), ...
                 'LineStyle', 'none', 'Marker', 'o', 'Color', 'Black', 'MarkerSize', 3 )
            
            % просмотр
            az1 = app.Slider_az.Value;
            el1 = app.Slider_el.Value;
            view(app.UIAxes,az1,el1);
            
        end

        % Value changed function: Slider_az
        function Slider_azValueChanged(app, event)
            az2 = app.Slider_az.Value;
            el2 = app.Slider_el.Value;
            view(app.UIAxes,az2,el2);
        end

        % Value changed function: Slider_el
        function Slider_elValueChanged(app, event)
            az3 = app.Slider_az.Value;
            el3 = app.Slider_el.Value;
            view(app.UIAxes,az3,el3);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Расчет разлета осколков стекла';
            app.UIAxes = uiaxes(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Высота над землей';
            app.Edit_H = uieditfield(app.UIFigure, 'numeric');
            app.Edit_H.Value = 10;
            app.YEditField_2Label = uilabel(app.UIFigure);
            app.YEditField_2Label.Text = 'Y';
            app.Edit_Y = uieditfield(app.UIFigure, 'numeric');
            app.Edit_Y.Value = 0.2;
            app.ZEditField_2Label = uilabel(app.UIFigure);
            app.ZEditField_2Label.Text = 'Z';
            app.Edit_Z = uieditfield(app.UIFigure, 'numeric');
            app.Edit_Z.Value = -0.5;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Координаты взрывного устройства:';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Рассчитать разлет осколков';
            app.YEditField_3Label = uilabel(app.UIFigure);
            app.YEditField_3Label.Text = 'Разбиения по Y';
            app.Edit_N = uieditfield(app.UIFigure, 'numeric');
            app.Edit_N.Value = 20;
            app.ZEditField_3Label = uilabel(app.UIFigure);
            app.ZEditField_3Label.Text = 'Разбиения по Z';
            app.Edit_M = uieditfield(app.UIFigure, 'numeric');
            app.Edit_M.Value = 20;
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.Edit_X = uieditfield(app.UIFigure, 'numeric');
            app.Edit_X.Value = -2;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Азимут';
            app.Slider_az = uislider(app.UIFigure);
            app.Slider_az.Limits = [-180 180];
            app.Slider_az.ValueChangedFcn = createCallbackFcn(app, @Slider_azValueChanged, true);
            app.Slider_az.Value = 30;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Угол обзора';
            app.Slider_el = uislider(app.UIFigure);
            app.Slider_el.Limits = [-90 90];
            app.Slider_el.ValueChangedFcn = createCallbackFcn(app, @Slider_elValueChanged, true);
            app.Slider_el.Value = 15;
            app.UIAxes_pict = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = lab01_explosion_example

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end