classdef lab02_pend_example < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Angle        matlab.ui.control.UIAxes
        Vel          matlab.ui.control.UIAxes
        Phase        matlab.ui.control.UIAxes
        SliderLabel  matlab.ui.control.Label
        Length       matlab.ui.control.Slider
        Label        matlab.ui.control.Label
        Theta        matlab.ui.control.Slider
        Label_3      matlab.ui.control.Label
        Thetad       matlab.ui.control.Slider
        Pend         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function [angle, vel] = pend_calc(~, l, t, init_angle, init_vel)
            g = 9.81;  % Ускорение свободного падения (м/с^2)
            % Начальные значения
            % Задание математической модели в форме Коши
            eqs = @(t, x) [x(2); -g/l*sin(x(1))];
            % Численное дифференцирование
            [~, sol] = ode45(eqs, t, [init_angle init_vel]);  % Численный решатель ode45
            % Извлечение результатов
            angle = sol(:, 1)'; % Значения угла
            vel = sol(:, 2)'; % Значения скорости
        end
    
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CalcFun();
        end

        % Callback function: Length, Length, Theta, Theta, Thetad, 
        % Thetad
        function CalcFun(app, event)
            T = 20;  % Время моделирования
            points = 200; % Количество точек симуляции
            l = app.Length.Value;
            theta = app.Theta.Value;
            thetad = app.Thetad.Value;
            if nargin > 1
                if event.Source == app.Length
                    l = event.Value;
                elseif event.Source == app.Theta
                    theta = event.Value;
                elseif event.Source == app.Thetad
                    thetad = event.Value;
                end
            end
            theta = deg2rad(theta);
            thetad = deg2rad(thetad);
            t = linspace(0, T, points)'; % Вектор времени
            [angle, vel] = app.pend_calc(l, t, theta, thetad); % Рассчитываем угол и скорость
            angle = rad2deg(angle);
            vel = rad2deg(vel);
            % Построение и настройка графиков
            plot(app.Angle, t, angle);
            plot(app.Vel, t, vel);
            plot(app.Phase, angle, vel);
            lims = minmax(angle);
            if any(lims)
                app.Phase.XLim = minmax(angle);
            end
            x = l*sin(theta);
            y = -l*cos(theta);
            plot(app.Pend, [0 x], [0 y], 'LineWidth', 3)
            hold(app.Pend, 'on')
            plot(app.Pend, x, y, 'o', 'MarkerSize', 10, 'MarkerFaceColor', [0.8500 0.3250 0.0980])
            hold(app.Pend, 'off');
            axis(app.Pend, 'equal');
            axis(app.Pend, 'off');
            axis(app.Pend, [-l l -l l]);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Симуляция маятника';
            app.Angle = uiaxes(app.UIFigure);
            title(app.Angle, 'Угол')
            xlabel(app.Angle, 't, с')
            ylabel(app.Angle, 'град')
            app.Vel = uiaxes(app.UIFigure);
            title(app.Vel, 'Скорость')
            xlabel(app.Vel, 't, с')
            ylabel(app.Vel, 'град/с')
            app.Phase = uiaxes(app.UIFigure);
            title(app.Phase, 'Фазовый портрет')
            xlabel(app.Phase, 'Угол, град')
            ylabel(app.Phase, 'Скорость, град/с')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Длина нити';
            app.Length = uislider(app.UIFigure);
            app.Length.Limits = [1 10];
            app.Length.ValueChangedFcn = createCallbackFcn(app, @CalcFun, true);
            app.Length.ValueChangingFcn = createCallbackFcn(app, @CalcFun, true);
            app.Length.Value = 1;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Начальный угол, град';
            app.Theta = uislider(app.UIFigure);
            app.Theta.Limits = [0 180];
            app.Theta.ValueChangedFcn = createCallbackFcn(app, @CalcFun, true);
            app.Theta.ValueChangingFcn = createCallbackFcn(app, @CalcFun, true);
            app.Theta.Value = 30;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Начальная скорость, град/с';
            app.Thetad = uislider(app.UIFigure);
            app.Thetad.Limits = [0 500];
            app.Thetad.ValueChangedFcn = createCallbackFcn(app, @CalcFun, true);
            app.Thetad.ValueChangingFcn = createCallbackFcn(app, @CalcFun, true);
            app.Pend = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = lab02_pend_example

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end